classdef GUI3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CannyButton                  matlab.ui.control.Button
        SobelButton                  matlab.ui.control.Button
        RobertButton                 matlab.ui.control.Button
        LogButton_2                  matlab.ui.control.Button
        PrewitButton                 matlab.ui.control.Button
        LaplaceButton                matlab.ui.control.Button
        FileInputnameEditField       matlab.ui.control.EditField
        FileInputnameEditFieldLabel  matlab.ui.control.Label
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LaplaceButton
        function LaplaceButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            inputImage = imread(filename);
            if ndims(inputImage) == 2
                inputImageGrey = inputImage
            elseif ndims(inputImage) == 3
                inputImageGrey = rgb2gray(inputImage)
            end
            imshow(inputImage, 'Parent', app.UIAxes);
                        
            resultImageLaplace = edgeLaplace(inputImageGrey);

            edgeImage = uint8(resultImageLaplace)
            imshow(edgeImage, 'Parent', app.UIAxes_2);
            labeledImage = bwlabel(edgeImage);
            
            % Close disconnected edges 
            closedImage = imclose(labeledImage,strel('line',10,0));
            
            % Fill inside the edges
            filledImage = imfill(closedImage, 'holes');
            
            openedImage = imopen(filledImage, strel(ones(3,3)));
            maskImage = bwareaopen(openedImage,1500);
            
            imshow(maskImage, 'Parent', app.UIAxes_3);
            segmentedImage = inputImage
            if ndims(inputImage) == 2
                segmentedImage(~maskImage) = 0;
            elseif ndims(inputImage) == 3
                colorMask = cat(3, maskImage, maskImage, maskImage);
                segmentedImage(~colorMask) = 0;
            end
            
            imshow(segmentedImage, 'Parent', app.UIAxes_4);
            % Laplace
            function result = edgeLaplace(img_input)
                % h = fspecial('log', [5, 5], 2);
                H = [1 1 1; 1 -8 1; 1 1 1];
                % H = [0 1 0; 1 -4 1; 0 1 0];
                result = uint8(convn(double(img_input), double(H)));
            
            end
            % Log Laplace of Gaussian
            function result = edgeLog(img_input)
                    % H = [0 1 0; 1 -4 1; 0 1 0];
                    h = fspecial('log');
                    result = uint8(convn(double(img_input), double(h)));
            end
            
            % sobel
            function result = edgeSobel(img_input)
                % sobelEdge = edge(grayImage, 'sobel');
                Sx = [-1 0 1; -2 0 2; -1 0 1];
                Sy = [1 2 1; 0 0 0; -1 -2 -1];
                Jx = convn(double(img_input), double(Sx), 'same');
                Jy = convn(double(img_input), double(Sy), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Prewit
            function result = edgePrewit(img_input)
                % prewittEdge = edge(grayImage, 'prewitt');
                Px = [-1 0 1; -1 0 1; -1 0 1];
                Py = [-1 -1 -1; 0 0 0; 1 1 1];
                Jx = convn(double(img_input), double(Px), 'same');
                Jy = convn(double(img_input), double(Py), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Roberts
            function result = edgeRoberts(img_input)
                % robertsEdge = edge(grayImage, 'roberts'); 
                Rx = [1 0; 0 -1];
                Ry = [0 1; -1 0];
                Jx = convn(double(img_input), double(Rx), 'same');
                Jy = convn(double(img_input), double(Ry), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Canny
            function result = edgeCanny(img_input)
                result = uint8(edge(img_input, 'canny'));
            end

        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            inputImage = imread(filename);
            if ndims(inputImage) == 2
                inputImageGrey = inputImage
            elseif ndims(inputImage) == 3
                inputImageGrey = rgb2gray(inputImage)
            end
            imshow(inputImage, 'Parent', app.UIAxes);
                        
            resultImageCanny = edgeCanny(inputImageGrey);
            
            edgeImage = uint8(resultImageCanny)
            imshow(resultImageCanny, 'Parent', app.UIAxes_2);
            labeledImage = bwlabel(edgeImage);
            
            % Close disconnected edges 
            closedImage = imclose(labeledImage,strel('line',10,0));
            
            % Fill inside the edges
            filledImage = imfill(closedImage, 'holes');
            
            openedImage = imopen(filledImage, strel(ones(3,3)));
            maskImage = bwareaopen(openedImage,1500);
            
            imshow(maskImage, 'Parent', app.UIAxes_3);
            segmentedImage = inputImage
            if ndims(inputImage) == 2
                segmentedImage(~maskImage) = 0;
            elseif ndims(inputImage) == 3
                colorMask = cat(3, maskImage, maskImage, maskImage);
                segmentedImage(~colorMask) = 0;
            end
            
            imshow(segmentedImage, 'Parent', app.UIAxes_4);
            % Laplace
            function result = edgeLaplace(img_input)
                % h = fspecial('log', [5, 5], 2);
                H = [1 1 1; 1 -8 1; 1 1 1];
                % H = [0 1 0; 1 -4 1; 0 1 0];
                result = uint8(convn(double(img_input), double(H)));
            
            end
            % Log Laplace of Gaussian
            function result = edgeLog(img_input)
                    % H = [0 1 0; 1 -4 1; 0 1 0];
                    h = fspecial('log');
                    result = uint8(convn(double(img_input), double(h)));
            end
            
            % sobel
            function result = edgeSobel(img_input)
                % sobelEdge = edge(grayImage, 'sobel');
                Sx = [-1 0 1; -2 0 2; -1 0 1];
                Sy = [1 2 1; 0 0 0; -1 -2 -1];
                Jx = convn(double(img_input), double(Sx), 'same');
                Jy = convn(double(img_input), double(Sy), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Prewit
            function result = edgePrewit(img_input)
                % prewittEdge = edge(grayImage, 'prewitt');
                Px = [-1 0 1; -1 0 1; -1 0 1];
                Py = [-1 -1 -1; 0 0 0; 1 1 1];
                Jx = convn(double(img_input), double(Px), 'same');
                Jy = convn(double(img_input), double(Py), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Roberts
            function result = edgeRoberts(img_input)
                % robertsEdge = edge(grayImage, 'roberts'); 
                Rx = [1 0; 0 -1];
                Ry = [0 1; -1 0];
                Jx = convn(double(img_input), double(Rx), 'same');
                Jy = convn(double(img_input), double(Ry), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Canny
            function result = edgeCanny(img_input)
                result = edge(img_input, 'canny');
            end

        end

        % Button pushed function: LogButton_2
        function LogButton_2Pushed(app, event)
            filename = app.FileInputnameEditField.Value
            inputImage = imread(filename);
            if ndims(inputImage) == 2
                inputImageGrey = inputImage
            elseif ndims(inputImage) == 3
                inputImageGrey = rgb2gray(inputImage)
            end
            imshow(inputImage, 'Parent', app.UIAxes);
                        
            resultImageLog = edgeLog(inputImageGrey);

            edgeImage = uint8(resultImageLog)
            imshow(edgeImage, 'Parent', app.UIAxes_2);
            labeledImage = bwlabel(edgeImage);
            
            % Close disconnected edges 
            closedImage = imclose(labeledImage,strel('line',10,0));
            
            % Fill inside the edges
            filledImage = imfill(closedImage, 'holes');
            
            openedImage = imopen(filledImage, strel(ones(3,3)));
            maskImage = bwareaopen(openedImage,1500);
            
            imshow(maskImage, 'Parent', app.UIAxes_3);
            segmentedImage = inputImage
            if ndims(inputImage) == 2
                segmentedImage(~maskImage) = 0;
            elseif ndims(inputImage) == 3
                colorMask = cat(3, maskImage, maskImage, maskImage);
                segmentedImage(~maskImage) = 0;
            end
            
            imshow(segmentedImage, 'Parent', app.UIAxes_4);
            % Laplace
            function result = edgeLaplace(img_input)
                % h = fspecial('log', [5, 5], 2);
                H = [1 1 1; 1 -8 1; 1 1 1];
                % H = [0 1 0; 1 -4 1; 0 1 0];
                result = uint8(convn(double(img_input), double(H)));
            
            end
            % Log Laplace of Gaussian
            function result = edgeLog(img_input)
                    % H = [0 1 0; 1 -4 1; 0 1 0];
                    h = fspecial('log');
                    result = uint8(convn(double(img_input), double(h)));
            end
            
            % sobel
            function result = edgeSobel(img_input)
                % sobelEdge = edge(grayImage, 'sobel');
                Sx = [-1 0 1; -2 0 2; -1 0 1];
                Sy = [1 2 1; 0 0 0; -1 -2 -1];
                Jx = convn(double(img_input), double(Sx), 'same');
                Jy = convn(double(img_input), double(Sy), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Prewit
            function result = edgePrewit(img_input)
                % prewittEdge = edge(grayImage, 'prewitt');
                Px = [-1 0 1; -1 0 1; -1 0 1];
                Py = [-1 -1 -1; 0 0 0; 1 1 1];
                Jx = convn(double(img_input), double(Px), 'same');
                Jy = convn(double(img_input), double(Py), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Roberts
            function result = edgeRoberts(img_input)
                % robertsEdge = edge(grayImage, 'roberts'); 
                Rx = [1 0; 0 -1];
                Ry = [0 1; -1 0];
                Jx = convn(double(img_input), double(Rx), 'same');
                Jy = convn(double(img_input), double(Ry), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Canny
            function result = edgeCanny(img_input)
                result = uint8(edge(img_input, 'canny'));
            end

        end

        % Button pushed function: RobertButton
        function RobertButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            inputImage = imread(filename);
            if ndims(inputImage) == 2
                inputImageGrey = inputImage
            elseif ndims(inputImage) == 3
                inputImageGrey = rgb2gray(inputImage)
            end
            imshow(inputImage, 'Parent', app.UIAxes);
                        
            resultImageRoberts = edgeRoberts(inputImageGrey);

            
            edgeImage = uint8(resultImageRoberts)
            imshow(edgeImage, 'Parent', app.UIAxes_2);
            labeledImage = bwlabel(edgeImage);
            
            % Close disconnected edges 
            closedImage = imclose(labeledImage,strel('line',10,0));
            
            % Fill inside the edges
            filledImage = imfill(closedImage, 'holes');
            
            openedImage = imopen(filledImage, strel(ones(3,3)));
            maskImage = bwareaopen(openedImage,1500);
            
            imshow(maskImage, 'Parent', app.UIAxes_3);
            segmentedImage = inputImage
            if ndims(inputImage) == 2
                segmentedImage(~maskImage) = 0;
            elseif ndims(inputImage) == 3
                colorMask = cat(3, maskImage, maskImage, maskImage);
                segmentedImage(~colorMask) = 0;
            end
            
            imshow(segmentedImage, 'Parent', app.UIAxes_4);
            % Laplace
            function result = edgeLaplace(img_input)
                % h = fspecial('log', [5, 5], 2);
                H = [1 1 1; 1 -8 1; 1 1 1];
                % H = [0 1 0; 1 -4 1; 0 1 0];
                result = uint8(convn(double(img_input), double(H)));
            
            end
            % Log Laplace of Gaussian
            function result = edgeLog(img_input)
                    % H = [0 1 0; 1 -4 1; 0 1 0];
                    h = fspecial('log');
                    result = uint8(convn(double(img_input), double(h)));
            end
            
            % sobel
            function result = edgeSobel(img_input)
                % sobelEdge = edge(grayImage, 'sobel');
                Sx = [-1 0 1; -2 0 2; -1 0 1];
                Sy = [1 2 1; 0 0 0; -1 -2 -1];
                Jx = convn(double(img_input), double(Sx), 'same');
                Jy = convn(double(img_input), double(Sy), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Prewit
            function result = edgePrewit(img_input)
                % prewittEdge = edge(grayImage, 'prewitt');
                Px = [-1 0 1; -1 0 1; -1 0 1];
                Py = [-1 -1 -1; 0 0 0; 1 1 1];
                Jx = convn(double(img_input), double(Px), 'same');
                Jy = convn(double(img_input), double(Py), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Roberts
            % Roberts edge detenction
            function result = edgeRoberts(img_input)
                % Menggunakan operator Roberts untuk mendeteksi tepi
                % robertsEdge = edge(grayImage, 'roberts'); 
                [M,N]=size(img_input);
                Rx = [1 0; 0 -1];
                Ry = [0 1; -1 0];
                Jx = convn(double(img_input), double(Rx), 'same');
                Jy = convn(double(img_input), double(Ry), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
                for i = 1:M
                    result(i,N)=0;
                    result(i,1)=0;
                end
                for j = 1:N
                    result(M,j)=0;
                    result(1,j)=0;
                end
            end
            
            % Canny
            function result = edgeCanny(img_input)
                result = uint8(edge(img_input, 'canny'));
            end

        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            inputImage = imread(filename);
            if ndims(inputImage) == 2
                inputImageGrey = inputImage
            elseif ndims(inputImage) == 3
                inputImageGrey = rgb2gray(inputImage)
            end
            imshow(inputImage, 'Parent', app.UIAxes);
                        
            resultImageSobel = edgeSobel(inputImageGrey);

            edgeImage = uint8(resultImageSobel)
            imshow(edgeImage, 'Parent', app.UIAxes_2);
            labeledImage = bwlabel(edgeImage);
            
            % Close disconnected edges 
            closedImage = imclose(labeledImage,strel('line',10,0));
            
            % Fill inside the edges
            filledImage = imfill(closedImage, 'holes');
            
            openedImage = imopen(filledImage, strel(ones(3,3)));
            maskImage = bwareaopen(openedImage,1500);
            
            imshow(maskImage, 'Parent', app.UIAxes_3);
            segmentedImage = inputImage
            if ndims(inputImage) == 2
                segmentedImage(~maskImage) = 0;
            elseif ndims(inputImage) == 3
                colorMask = cat(3, maskImage, maskImage, maskImage);
                segmentedImage(~colorMask) = 0;
            end
            
            imshow(segmentedImage, 'Parent', app.UIAxes_4);
            % Laplace
            function result = edgeLaplace(img_input)
                % h = fspecial('log', [5, 5], 2);
                H = [1 1 1; 1 -8 1; 1 1 1];
                % H = [0 1 0; 1 -4 1; 0 1 0];
                result = uint8(convn(double(img_input), double(H)));
            
            end
            % Log Laplace of Gaussian
            function result = edgeLog(img_input)
                    % H = [0 1 0; 1 -4 1; 0 1 0];
                    h = fspecial('log');
                    result = uint8(convn(double(img_input), double(h)));
            end
            
            % sobel
            function result = edgeSobel(img_input)
                 % Menggunakan operator Sobel untuk mendeteksi tepi
                % sobelEdge = edge(grayImage, 'sobel');
                [M,N]=size(img_input);
                Sx = [-1 0 1; -2 0 2; -1 0 1];
                Sy = [1 2 1; 0 0 0; -1 -2 -1];
                Jx = convn(double(img_input), double(Sx), 'same');
                Jy = convn(double(img_input), double(Sy), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
                for i = 1:M
                    result(i,1)=0;
                    result(i,N)=0;
                end
                for j = 1:N
                    result(1,j)=0;
                    result(M,j)=0;
                end
            end
            
            % Prewit
            function result = edgePrewit(img_input)
                % prewittEdge = edge(grayImage, 'prewitt');
                Px = [-1 0 1; -1 0 1; -1 0 1];
                Py = [-1 -1 -1; 0 0 0; 1 1 1];
                Jx = convn(double(img_input), double(Px), 'same');
                Jy = convn(double(img_input), double(Py), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Roberts
            function result = edgeRoberts(img_input)
                % robertsEdge = edge(grayImage, 'roberts'); 
                Rx = [1 0; 0 -1];
                Ry = [0 1; -1 0];
                Jx = convn(double(img_input), double(Rx), 'same');
                Jy = convn(double(img_input), double(Ry), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Canny
            function result = edgeCanny(img_input)
                result = uint8(edge(img_input, 'canny'));
            end
        end

        % Button pushed function: PrewitButton
        function PrewitButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            inputImage = imread(filename);
            if ndims(inputImage) == 2
                inputImageGrey = inputImage
            elseif ndims(inputImage) == 3
                inputImageGrey = rgb2gray(inputImage)
            end
            imshow(inputImage, 'Parent', app.UIAxes);
                        
            resultImagePrewit = edgePrewit(inputImageGrey);

            
            edgeImage = uint8(resultImagePrewit)
            imshow(edgeImage, 'Parent', app.UIAxes_2);
            labeledImage = bwlabel(edgeImage);
            
            % Close disconnected edges 
            closedImage = imclose(labeledImage,strel('line',10,0));
            
            % Fill inside the edges
            filledImage = imfill(closedImage, 'holes');
            
            openedImage = imopen(filledImage, strel(ones(3,3)));
            maskImage = bwareaopen(openedImage,1500);
            
            imshow(maskImage, 'Parent', app.UIAxes_3);
            segmentedImage = inputImage
            if ndims(inputImage) == 2
                segmentedImage(~maskImage) = 0;
            elseif ndims(inputImage) == 3
                colorMask = cat(3, maskImage, maskImage, maskImage);
                segmentedImage(~colorMask) = 0;
            end
            
            imshow(segmentedImage, 'Parent', app.UIAxes_4);
            % Laplace
            function result = edgeLaplace(img_input)
                % h = fspecial('log', [5, 5], 2);
                H = [1 1 1; 1 -8 1; 1 1 1];
                % H = [0 1 0; 1 -4 1; 0 1 0];
                result = uint8(convn(double(img_input), double(H)));
            
            end
            % Log Laplace of Gaussian
            function result = edgeLog(img_input)
                    % H = [0 1 0; 1 -4 1; 0 1 0];
                    h = fspecial('log');
                    result = uint8(convn(double(img_input), double(h)));
            end
            
            % sobel
            function result = edgeSobel(img_input)
                % sobelEdge = edge(grayImage, 'sobel');
                Sx = [-1 0 1; -2 0 2; -1 0 1];
                Sy = [1 2 1; 0 0 0; -1 -2 -1];
                Jx = convn(double(img_input), double(Sx), 'same');
                Jy = convn(double(img_input), double(Sy), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Prewit
            function result = edgePrewit(img_input)
                % Menggunakan operator Prewitt untuk mendeteksi tepi
                % prewittEdge = edge(grayImage, 'prewitt');
                [M,N]=size(img_input);
                Px = [-1 0 1; -1 0 1; -1 0 1];
                Py = [-1 -1 -1; 0 0 0; 1 1 1];
                Jx = convn(double(img_input), double(Px), 'same');
                Jy = convn(double(img_input), double(Py), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
                for i = 1:M
                    result(i,1)=0;
                    result(i,N)=0;
                end
                for j = 1:N
                    result(1,j)=0;
                    result(M,j)=0;
                end
            end
            
            % Roberts
            function result = edgeRoberts(img_input)
                % robertsEdge = edge(grayImage, 'roberts'); 
                Rx = [1 0; 0 -1];
                Ry = [0 1; -1 0];
                Jx = convn(double(img_input), double(Rx), 'same');
                Jy = convn(double(img_input), double(Ry), 'same');
                result = uint8(sqrt(Jx.^2 + Jy.^2));
            end
            
            % Canny
            function result = edgeCanny(img_input)
                result = uint8(edge(img_input, 'canny'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Edge Image')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Segment Image')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Output Image')
            app.FileInputnameEditFieldLabel = uilabel(app.UIFigure);
            app.FileInputnameEditFieldLabel.Text = 'File Input name';
            app.FileInputnameEditField = uieditfield(app.UIFigure, 'text');
            app.FileInputnameEditField.Value = '1.jpg';
            app.LaplaceButton = uibutton(app.UIFigure, 'push');
            app.LaplaceButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceButtonPushed, true);
            app.LaplaceButton.Text = 'Laplace';
            app.PrewitButton = uibutton(app.UIFigure, 'push');
            app.PrewitButton.ButtonPushedFcn = createCallbackFcn(app, @PrewitButtonPushed, true);
            app.PrewitButton.Text = 'Prewit';
            app.LogButton_2 = uibutton(app.UIFigure, 'push');
            app.LogButton_2.ButtonPushedFcn = createCallbackFcn(app, @LogButton_2Pushed, true);
            app.LogButton_2.Text = 'Log';
            app.RobertButton = uibutton(app.UIFigure, 'push');
            app.RobertButton.ButtonPushedFcn = createCallbackFcn(app, @RobertButtonPushed, true);
            app.RobertButton.Text = 'Robert';
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.CannyButton = uibutton(app.UIFigure, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end